classdef app_black < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        BasicPanel                      matlab.ui.container.Panel
        ImageEditFieldLabel             matlab.ui.control.Label
        ImageEditField                  matlab.ui.control.EditField
        LoadImageButton                 matlab.ui.control.Button
        CropImageButton                 matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        PrefilterPanel                  matlab.ui.container.Panel
        XSizeEditFieldLabel             matlab.ui.control.Label
        MedianXSizeEditField            matlab.ui.control.NumericEditField
        YSizeEditFieldLabel             matlab.ui.control.Label
        MedianYSizeEditField            matlab.ui.control.NumericEditField
        MedianFilterButton              matlab.ui.control.StateButton
        ContrastEnhancementPanel        matlab.ui.container.Panel
        MultiscaleTophatButton          matlab.ui.control.StateButton
        AdaptiveHistEqButton            matlab.ui.control.StateButton
        TopHatScalesEditField           matlab.ui.control.NumericEditField
        NumberofScalesLabel             matlab.ui.control.Label
        PostfilterPanel                 matlab.ui.container.Panel
        UnsharpMaskingButton            matlab.ui.control.StateButton
        GuidedfilterButton              matlab.ui.control.StateButton
        RadiusLabel                     matlab.ui.control.Label
        SharpenRadiusEditField          matlab.ui.control.NumericEditField
        AmountLabel                     matlab.ui.control.Label
        SharpenAmountEditField          matlab.ui.control.NumericEditField
        UpdateSegmentationButton        matlab.ui.control.Button
        SegmentationTab                 matlab.ui.container.Tab
        VesselnessPanel                 matlab.ui.container.Panel
        StartLabel                      matlab.ui.control.Label
        VesselNessScalesStartEditField  matlab.ui.control.NumericEditField
        StepLabel                       matlab.ui.control.Label
        VesselNessScalesStepEditField   matlab.ui.control.NumericEditField
        EndLabel                        matlab.ui.control.Label
        VesselNessScalesEndEditField    matlab.ui.control.NumericEditField
        ScalesLabel                     matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        KernelSizeEditFieldLabel        matlab.ui.control.Label
        KernelSizeEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        MorphologicalOperationsPanel    matlab.ui.container.Panel
        FillSmallHolesButton            matlab.ui.control.StateButton
        HoleSizeThresholdLabel          matlab.ui.control.Label
        HoleSizeEditField               matlab.ui.control.NumericEditField
        NLabel                          matlab.ui.control.Label
        NLargestAreaEditField           matlab.ui.control.NumericEditField
        NLargestAreaLabel               matlab.ui.control.Label
        RidgeDetectionPanel             matlab.ui.container.Panel
        MLSECButton                     matlab.ui.control.StateButton
        KernelSizeLabel                 matlab.ui.control.Label
        MLSECKernelEditField            matlab.ui.control.NumericEditField
        ScaleLabel                      matlab.ui.control.Label
        MLSECScaleEditField             matlab.ui.control.NumericEditField
        CreasefactorLabel               matlab.ui.control.Label
        MLSECcEditField                 matlab.ui.control.NumericEditField
        SkeletonButton                  matlab.ui.control.StateButton
        ConnectivityButtonGroup         matlab.ui.container.ButtonGroup
        FourButton                      matlab.ui.control.RadioButton
        EightButton                     matlab.ui.control.RadioButton
        ThresholdLabel                  matlab.ui.control.Label
        MLSECThreshold                  matlab.ui.control.NumericEditField
        DisplayButtonGroup              matlab.ui.container.ButtonGroup
        VesselnessButton                matlab.ui.control.ToggleButton
        SegmentationButton              matlab.ui.control.ToggleButton
        RidgesButton                    matlab.ui.control.ToggleButton
        DisplayOnOriginalImageButton    matlab.ui.control.StateButton
        RightPanel                      matlab.ui.container.Panel
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        I % Description
        T % Description
        P % Description
        V % Description
        M % Description
        R % Description
    end
    
    methods (Access = private)
        
        function LoadImage(app)
            app.I = imread(app.ImageEditField.Value);
            if size(app.I, 3) == 3
                app.I = rgb2gray(app.I);
            end
            app.I = im2double(imresize(app.I, [256 256]));
            imshow(app.I, 'Parent', app.UIAxes1);
            app.Preprocess();
            axis(app.UIAxes1, 'image');
            axis(app.UIAxes2, 'image');
            axis(app.UIAxes3, 'image');
        end
        
        function Preprocess(app)
            app.P = app.I;
            if app.MedianFilterButton.Value
                app.P = medfilt2(app.I, [app.MedianXSizeEditField.Value, app.MedianYSizeEditField.Value]);
            end
            if app.MultiscaleTophatButton.Value
                app.P = app.multiscale_tophat(app.P, app.TopHatScalesEditField.Value);
            end
            if app.AdaptiveHistEqButton.Value
                app.P = adapthisteq(app.P);
            end
            if app.UnsharpMaskingButton.Value
                app.P = imsharpen(app.P, 'Radius', app.SharpenRadiusEditField.Value, 'Amount', app.SharpenAmountEditField.Value);
            end
            if app.GuidedfilterButton.Value
                app.P = imguidedfilter(app.P);
            end
            imshow(app.P, 'Parent', app.UIAxes2);
        end
        
        function Vesselness(app)
            [height, width] = size(app.I);
            sigmas = app.VesselNessScalesStartEditField.Value:app.VesselNessScalesStepEditField.Value:app.VesselNessScalesEndEditField.Value;
            app.T = zeros(height, width, size(sigmas, 2));
            outer_i = 1;
            for sigma = sigmas
                g = fspecial('gaussian', app.KernelSizeEditField.Value, sigma*20);
                [gx, gy] = gradient(g);
                [gxx, gxy] = gradient(gx);
                [~, gyy] = gradient(gy);
                Ixx = conv2(app.I, gxx, 'same');
                Ixy = conv2(app.I, gxy, 'same');
                Iyy = conv2(app.I, gyy, 'same');
                for i = 1:height
                    for j = 1:width
                        S = [Ixx(i,j) Ixy(i,j); Ixy(i,j) Iyy(i,j)];
                        [~, d] = eig(S);
                        ev = abs(diag(d));
                        t = ev(2) / (sigma * app.P(i, j));
                        if ev(2) > ev(1)
                            app.T(i, j, outer_i) = -t^2;
                        end
                    end
                end
                outer_i = outer_i + 1;
            end
            app.VesselnessThreshold();
        end
        
        function VesselnessThreshold(app)
            V_matrix = 1 - exp(app.T/app.ThresholdSlider.Value);
            app.V = max(V_matrix, [], 3);
            
            if app.VesselnessButton.Value
                show = app.V;
                if app.DisplayOnOriginalImageButton.Value
                    show = cat(3, app.I, app.I, app.I);
                    show(:, :, 2) = show(:, :, 2) + app.V*0.5;
                end
                imshow(show, 'Parent', app.UIAxes3);
            else
                app.Morphological();
            end
            
        end
        
        
        function Morphological(app)
            app.M = app.V > 0.1;
            app.M = bwareafilt(app.M, app.NLargestAreaEditField.Value);
            if app.FillSmallHolesButton.Value
                filled = imfill(app.M, 'holes');
                holes = filled & ~app.M;
                bigs = bwareaopen(holes, app.HoleSizeEditField.Value);
                smalls = holes & ~bigs;
                app.M = app.M | smalls;
            end
            if app.SegmentationButton.Value
                show = app.M;
                if app.DisplayOnOriginalImageButton.Value
                    segments = bwperim(app.M);
                    show = imoverlay(app.I, segments, 'cyan');
                end
                imshow(show, 'Parent', app.UIAxes3);
            else
                app.Ridge();
            end
        end
        
        function Ridge(app)
            if app.SkeletonButton.Value
                if app.EightButton.Value
                    app.R = bwmorph(app.M, 'skel', Inf);
                else
                    app.R = bwskel(app.M);
                end
            else
                app.R = app.MLSEC(app.P, app.MLSECKernelEditField.Value, app.MLSECScaleEditField.Value, app.MLSECcEditField.Value).*app.M > app.MLSECThreshold.Value;
            end
            if app.RidgesButton.Value
                show = app.R;
                if app.DisplayOnOriginalImageButton.Value
                    show = imoverlay(app.I, app.R, 'red');
                end
                imshow(show, 'Parent', app.UIAxes3);
            end
        end
        
        function [wth, bth] = tophat(~, img, n)
            se = strel('square', n);
            img_open = imopen(img, se);
            img_close = imclose(img, se);
            wth = img - img_open;
            bth = img_close - img;
        end
        
        function img = multiscale_tophat(app, img, n)
            [wth, bth] = app.tophat(img, 1);
            fdw = zeros(size(img), 'double');
            fdb = zeros(size(img), 'double');
            fcw = wth;
            fcb = bth;
        
            for i = 2:n
                [wth_i, bth_i] = app.tophat(img, i);
                fcw = max(fcw, wth_i);
                fcb = max(fcb, bth_i);
                fdw = max(fdw, wth_i - wth);
                fdb = max(fdb, bth_i - bth);
                [wth, bth] = deal(wth_i, bth_i);
            end
        
            img = img + fcw + fdw - fcb - fdb;
        end
        
        function valleys = MLSEC(~, img, L, sigma, c)
            [height, width] = size(img);
            [Lx, Ly] = gradient(img);
            Sxx = Lx.^2;
            Sxy = Lx.*Ly;
            Syy = Ly.^2;
        
            G = fspecial('gaussian', L, sigma);
            Sxx = imfilter(Sxx, G, 'conv', 'same', 'replicate');
            Sxy = imfilter(Sxy, G, 'conv', 'same', 'replicate');
            Syy = imfilter(Syy, G, 'conv', 'same', 'replicate');
        
            w_prime = zeros(height, width, 2);
            C = zeros(height, width);
        
            for i = 1:height
                for j = 1:width
                    S = [Sxx(i,j) Sxy(i,j); Sxy(i,j) Syy(i,j)];
                    [v, d] = eig(S);
                    ev = diag(d);
                    [~, max_idx] = max(ev);
                    w_prime(i, j, :) = v(:, max_idx);
                    eig_diff = (ev(1) -  ev(2))^2;
                    C(i, j) = 1 - exp(-eig_diff^2 / (2 * c^2));
                end
            end
        
            sign_dot = sign(w_prime(:, :, 1) .* Lx + w_prime(:, :, 2) .* Ly);
            w_bar_x = sign_dot .* w_prime(:, :, 1);
            w_bar_y = sign_dot .* w_prime(:, :, 2);
        
            k_bar2 = zeros(height, width);
        
            for i = 2:(height-1)
                for j = 2:(width-1)
                    k_bar2(i, j) = -0.5 * (w_bar_x(i+1, j) - w_bar_x(i-1, j) + w_bar_y(i, j+1) - w_bar_y(i, j-1));
                end
            end
        
            valleys = k_bar2.*C;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg; *.JPG; *.jpeg; *.JPEG; *.png; *.PNG;'}, 'Select Image', 'MultiSelect', 'off');
            app.ImageEditField.Value = fullfile(pathname, filename);
        end

        % Button pushed function: CropImageButton
        function CropImageButtonPushed(app, event)
            app.LoadImage();
            app.I = imcrop(app.I);
            app.I = imresize(app.I, [256 256]);
            imshow(app.I, 'Parent', app.UIAxes1);
            app.Preprocess();
            app.Vesselness();
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.LoadImage();
            app.Vesselness();
        end

        % Value changed function: MedianFilterButton
        function MedianFilterButtonValueChanged(app, event)
            app.Preprocess();
        end

        % Value changed function: MultiscaleTophatButton
        function MultiscaleTophatButtonValueChanged(app, event)
            app.AdaptiveHistEqButton.Value = false;
            app.Preprocess();
        end

        % Value changed function: MedianXSizeEditField, 
        % MedianYSizeEditField, TopHatScalesEditField
        function PreprocessUpdate(app, event)
            app.Preprocess();
        end

        % Value changed function: AdaptiveHistEqButton
        function AdaptiveHistEqButtonValueChanged(app, event)
            app.MultiscaleTophatButton.Value = false;
            app.Preprocess();
        end

        % Value changed function: UnsharpMaskingButton
        function UnsharpMaskingButtonValueChanged(app, event)
            app.Preprocess();
        end

        % Value changed function: GuidedfilterButton
        function GuidedfilterButtonValueChanged(app, event)
            app.Preprocess();
        end

        % Callback function: KernelSizeEditField, 
        % UpdateSegmentationButton, VesselNessScalesEndEditField, 
        % VesselNessScalesStartEditField, 
        % VesselNessScalesStepEditField
        function VesselNessValueChanged(app, event)
            app.Vesselness();
        end

        % Callback function: ThresholdSlider, ThresholdSlider
        function ThresholdSliderValueChange(app, event)
            app.ThresholdEditField.Value = event.Value;
            app.VesselnessThreshold();
        end

        % Value changed function: FillSmallHolesButton, 
        % HoleSizeEditField, NLargestAreaEditField
        function MorphologicalValueChanged(app, event)
            app.Morphological();
        end

        % Callback function: ConnectivityButtonGroup, 
        % MLSECKernelEditField, MLSECScaleEditField, 
        % MLSECThreshold, MLSECcEditField
        function RidgeValueChanged(app, event)
            app.Ridge();
        end

        % Value changed function: MLSECButton
        function MLSECButtonValueChanged(app, event)
            if app.MLSECButton.Value
                app.SkeletonButton.Value = false;
            else
                app.SkeletonButton.Value = true;
            end
            app.Ridge();
        end

        % Value changed function: SkeletonButton
        function SkeletonButtonValueChanged(app, event)
            if app.SkeletonButton.Value
                app.MLSECButton.Value = false;
            else
                app.MLSECButton.Value = true;
            end
            app.Ridge();
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            if app.VesselnessButton.Value
                show = app.V;
                if app.DisplayOnOriginalImageButton.Value
                    show = cat(3, app.I, app.I, app.I);
                    show(:, :, 2) = show(:, :, 2) + app.V*0.5;
                end
                imshow(show, 'Parent', app.UIAxes3);
            elseif app.SegmentationButton.Value
                app.Morphological();
            else
                app.Ridge();
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;
            app.VesselnessThreshold();
        end

        % Value changed function: DisplayOnOriginalImageButton
        function DisplayOnOriginalImageButtonValueChanged(app, event)
            app.DisplayButtonGroupSelectionChanged();
          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {642, 642};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {399, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.BasicPanel = uipanel(app.PreprocessingTab);
            app.BasicPanel.Title = 'Basic';
            app.ImageEditFieldLabel = uilabel(app.BasicPanel);
            app.ImageEditFieldLabel.Text = 'Image';
            app.ImageEditField = uieditfield(app.BasicPanel, 'text');
            app.LoadImageButton = uibutton(app.BasicPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CropImageButton = uibutton(app.BasicPanel, 'push');
            app.CropImageButton.ButtonPushedFcn = createCallbackFcn(app, @CropImageButtonPushed, true);
            app.CropImageButton.Text = 'Crop Image';
            app.OpenButton = uibutton(app.BasicPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.PrefilterPanel = uipanel(app.PreprocessingTab);
            app.PrefilterPanel.Title = 'Prefilter';
            app.XSizeEditFieldLabel = uilabel(app.PrefilterPanel);
            app.XSizeEditFieldLabel.Text = 'X Size';
            app.MedianXSizeEditField = uieditfield(app.PrefilterPanel, 'numeric');
            app.MedianXSizeEditField.RoundFractionalValues = 'on';
            app.MedianXSizeEditField.ValueDisplayFormat = '%11g';
            app.MedianXSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PreprocessUpdate, true);
            app.MedianXSizeEditField.Value = 2;
            app.YSizeEditFieldLabel = uilabel(app.PrefilterPanel);
            app.YSizeEditFieldLabel.Text = 'Y Size';
            app.MedianYSizeEditField = uieditfield(app.PrefilterPanel, 'numeric');
            app.MedianYSizeEditField.RoundFractionalValues = 'on';
            app.MedianYSizeEditField.ValueDisplayFormat = '%11g';
            app.MedianYSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PreprocessUpdate, true);
            app.MedianYSizeEditField.Value = 2;
            app.MedianFilterButton = uibutton(app.PrefilterPanel, 'state');
            app.MedianFilterButton.ValueChangedFcn = createCallbackFcn(app, @MedianFilterButtonValueChanged, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.MedianFilterButton.Value = true;
            app.ContrastEnhancementPanel = uipanel(app.PreprocessingTab);
            app.ContrastEnhancementPanel.Title = 'Contrast Enhancement';
            app.MultiscaleTophatButton = uibutton(app.ContrastEnhancementPanel, 'state');
            app.MultiscaleTophatButton.ValueChangedFcn = createCallbackFcn(app, @MultiscaleTophatButtonValueChanged, true);
            app.MultiscaleTophatButton.Text = 'Multiscale Tophat';
            app.MultiscaleTophatButton.Value = true;
            app.AdaptiveHistEqButton = uibutton(app.ContrastEnhancementPanel, 'state');
            app.AdaptiveHistEqButton.ValueChangedFcn = createCallbackFcn(app, @AdaptiveHistEqButtonValueChanged, true);
            app.AdaptiveHistEqButton.Text = 'Adaptive Hist. Eq.';
            app.TopHatScalesEditField = uieditfield(app.ContrastEnhancementPanel, 'numeric');
            app.TopHatScalesEditField.RoundFractionalValues = 'on';
            app.TopHatScalesEditField.ValueDisplayFormat = '%11g';
            app.TopHatScalesEditField.ValueChangedFcn = createCallbackFcn(app, @PreprocessUpdate, true);
            app.TopHatScalesEditField.Value = 16;
            app.NumberofScalesLabel = uilabel(app.ContrastEnhancementPanel);
            app.NumberofScalesLabel.Text = 'Number of Scales';
            app.PostfilterPanel = uipanel(app.PreprocessingTab);
            app.PostfilterPanel.Title = 'Postfilter';
            app.UnsharpMaskingButton = uibutton(app.PostfilterPanel, 'state');
            app.UnsharpMaskingButton.ValueChangedFcn = createCallbackFcn(app, @UnsharpMaskingButtonValueChanged, true);
            app.UnsharpMaskingButton.Text = 'Unsharp Masking';
            app.UnsharpMaskingButton.Value = true;
            app.GuidedfilterButton = uibutton(app.PostfilterPanel, 'state');
            app.GuidedfilterButton.ValueChangedFcn = createCallbackFcn(app, @GuidedfilterButtonValueChanged, true);
            app.GuidedfilterButton.Text = 'Guided filter';
            app.RadiusLabel = uilabel(app.PostfilterPanel);
            app.RadiusLabel.Text = 'Radius';
            app.SharpenRadiusEditField = uieditfield(app.PostfilterPanel, 'numeric');
            app.SharpenRadiusEditField.ValueDisplayFormat = '%11.2g';
            app.SharpenRadiusEditField.Value = 1;
            app.AmountLabel = uilabel(app.PostfilterPanel);
            app.AmountLabel.Text = 'Amount';
            app.SharpenAmountEditField = uieditfield(app.PostfilterPanel, 'numeric');
            app.SharpenAmountEditField.ValueDisplayFormat = '%11.2g';
            app.SharpenAmountEditField.Value = 0.5;
            app.UpdateSegmentationButton = uibutton(app.PreprocessingTab, 'push');
            app.UpdateSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @VesselNessValueChanged, true);
            app.UpdateSegmentationButton.Text = 'Update Segmentation';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.VesselnessPanel = uipanel(app.SegmentationTab);
            app.VesselnessPanel.Title = 'Vesselness';
            app.StartLabel = uilabel(app.VesselnessPanel);
            app.StartLabel.Text = 'Start';
            app.VesselNessScalesStartEditField = uieditfield(app.VesselnessPanel, 'numeric');
            app.VesselNessScalesStartEditField.ValueDisplayFormat = '%11.2g';
            app.VesselNessScalesStartEditField.ValueChangedFcn = createCallbackFcn(app, @VesselNessValueChanged, true);
            app.VesselNessScalesStartEditField.Value = 0.04;
            app.StepLabel = uilabel(app.VesselnessPanel);
            app.StepLabel.Text = 'Step';
            app.VesselNessScalesStepEditField = uieditfield(app.VesselnessPanel, 'numeric');
            app.VesselNessScalesStepEditField.ValueDisplayFormat = '%11.2g';
            app.VesselNessScalesStepEditField.ValueChangedFcn = createCallbackFcn(app, @VesselNessValueChanged, true);
            app.VesselNessScalesStepEditField.Value = 0.01;
            app.EndLabel = uilabel(app.VesselnessPanel);
            app.EndLabel.Text = 'End';
            app.VesselNessScalesEndEditField = uieditfield(app.VesselnessPanel, 'numeric');
            app.VesselNessScalesEndEditField.ValueDisplayFormat = '%11.2g';
            app.VesselNessScalesEndEditField.ValueChangedFcn = createCallbackFcn(app, @VesselNessValueChanged, true);
            app.VesselNessScalesEndEditField.Value = 0.15;
            app.ScalesLabel = uilabel(app.VesselnessPanel);
            app.ScalesLabel.Text = 'Scales';
            app.ThresholdSlider = uislider(app.VesselnessPanel);
            app.ThresholdSlider.Limits = [0 30];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChange, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChange, true);
            app.ThresholdSlider.Value = 10;
            app.KernelSizeEditFieldLabel = uilabel(app.VesselnessPanel);
            app.KernelSizeEditFieldLabel.Text = 'Kernel Size';
            app.KernelSizeEditField = uieditfield(app.VesselnessPanel, 'numeric');
            app.KernelSizeEditField.ValueChangedFcn = createCallbackFcn(app, @VesselNessValueChanged, true);
            app.KernelSizeEditField.Value = 16;
            app.ThresholdEditFieldLabel = uilabel(app.VesselnessPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.VesselnessPanel, 'numeric');
            app.ThresholdEditField.ValueDisplayFormat = '%11.2g';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 10;
            app.MorphologicalOperationsPanel = uipanel(app.SegmentationTab);
            app.MorphologicalOperationsPanel.Title = 'Morphological Operations';
            app.FillSmallHolesButton = uibutton(app.MorphologicalOperationsPanel, 'state');
            app.FillSmallHolesButton.ValueChangedFcn = createCallbackFcn(app, @MorphologicalValueChanged, true);
            app.FillSmallHolesButton.Text = 'Fill Small Holes';
            app.FillSmallHolesButton.Value = true;
            app.HoleSizeThresholdLabel = uilabel(app.MorphologicalOperationsPanel);
            app.HoleSizeThresholdLabel.Text = 'Hole Size Threshold';
            app.HoleSizeEditField = uieditfield(app.MorphologicalOperationsPanel, 'numeric');
            app.HoleSizeEditField.RoundFractionalValues = 'on';
            app.HoleSizeEditField.ValueDisplayFormat = '%11g';
            app.HoleSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MorphologicalValueChanged, true);
            app.HoleSizeEditField.Value = 50;
            app.NLabel = uilabel(app.MorphologicalOperationsPanel);
            app.NLabel.Text = 'N';
            app.NLargestAreaEditField = uieditfield(app.MorphologicalOperationsPanel, 'numeric');
            app.NLargestAreaEditField.RoundFractionalValues = 'on';
            app.NLargestAreaEditField.ValueDisplayFormat = '%11g';
            app.NLargestAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MorphologicalValueChanged, true);
            app.NLargestAreaEditField.Value = 1;
            app.NLargestAreaLabel = uilabel(app.MorphologicalOperationsPanel);
            app.NLargestAreaLabel.Text = 'N Largest Area';
            app.RidgeDetectionPanel = uipanel(app.SegmentationTab);
            app.RidgeDetectionPanel.Title = 'Ridge Detection';
            app.MLSECButton = uibutton(app.RidgeDetectionPanel, 'state');
            app.MLSECButton.ValueChangedFcn = createCallbackFcn(app, @MLSECButtonValueChanged, true);
            app.MLSECButton.Text = 'MLSEC';
            app.KernelSizeLabel = uilabel(app.RidgeDetectionPanel);
            app.KernelSizeLabel.Text = 'Kernel Size';
            app.MLSECKernelEditField = uieditfield(app.RidgeDetectionPanel, 'numeric');
            app.MLSECKernelEditField.RoundFractionalValues = 'on';
            app.MLSECKernelEditField.ValueDisplayFormat = '%11g';
            app.MLSECKernelEditField.ValueChangedFcn = createCallbackFcn(app, @RidgeValueChanged, true);
            app.MLSECKernelEditField.Value = 8;
            app.ScaleLabel = uilabel(app.RidgeDetectionPanel);
            app.ScaleLabel.Text = 'Scale';
            app.MLSECScaleEditField = uieditfield(app.RidgeDetectionPanel, 'numeric');
            app.MLSECScaleEditField.RoundFractionalValues = 'on';
            app.MLSECScaleEditField.ValueDisplayFormat = '%11g';
            app.MLSECScaleEditField.ValueChangedFcn = createCallbackFcn(app, @RidgeValueChanged, true);
            app.MLSECScaleEditField.Value = 4;
            app.CreasefactorLabel = uilabel(app.RidgeDetectionPanel);
            app.CreasefactorLabel.Text = 'Crease factor';
            app.MLSECcEditField = uieditfield(app.RidgeDetectionPanel, 'numeric');
            app.MLSECcEditField.ValueDisplayFormat = '%11.16g';
            app.MLSECcEditField.ValueChangedFcn = createCallbackFcn(app, @RidgeValueChanged, true);
            app.MLSECcEditField.Value = 1e-08;
            app.SkeletonButton = uibutton(app.RidgeDetectionPanel, 'state');
            app.SkeletonButton.ValueChangedFcn = createCallbackFcn(app, @SkeletonButtonValueChanged, true);
            app.SkeletonButton.Text = 'Skeleton';
            app.SkeletonButton.Value = true;
            app.ConnectivityButtonGroup = uibuttongroup(app.RidgeDetectionPanel);
            app.ConnectivityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RidgeValueChanged, true);
            app.ConnectivityButtonGroup.Title = 'Connectivity';
            app.FourButton = uiradiobutton(app.ConnectivityButtonGroup);
            app.FourButton.Text = ' Four';
            app.FourButton.Value = true;
            app.EightButton = uiradiobutton(app.ConnectivityButtonGroup);
            app.EightButton.Text = ' Eight';
            app.ThresholdLabel = uilabel(app.RidgeDetectionPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.MLSECThreshold = uieditfield(app.RidgeDetectionPanel, 'numeric');
            app.MLSECThreshold.ValueDisplayFormat = '%11.16g';
            app.MLSECThreshold.ValueChangedFcn = createCallbackFcn(app, @RidgeValueChanged, true);
            app.MLSECThreshold.Value = 0.1;
            app.DisplayButtonGroup = uibuttongroup(app.SegmentationTab);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.VesselnessButton = uitogglebutton(app.DisplayButtonGroup);
            app.VesselnessButton.Text = 'Vesselness';
            app.VesselnessButton.Value = true;
            app.SegmentationButton = uitogglebutton(app.DisplayButtonGroup);
            app.SegmentationButton.Text = 'Segmentation';
            app.RidgesButton = uitogglebutton(app.DisplayButtonGroup);
            app.RidgesButton.Text = 'Ridges';
            app.DisplayOnOriginalImageButton = uibutton(app.DisplayButtonGroup, 'state');
            app.DisplayOnOriginalImageButton.ValueChangedFcn = createCallbackFcn(app, @DisplayOnOriginalImageButtonValueChanged, true);
            app.DisplayOnOriginalImageButton.Text = 'Display On Original Image';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes1 = uiaxes(app.RightPanel);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_black

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end